classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CapturarTableroButton  matlab.ui.control.StateButton
        CalibrarCamButton      matlab.ui.control.StateButton
        CapturarPiezasButton   matlab.ui.control.StateButton
        ClasificarButton       matlab.ui.control.StateButton
        CargarPiezasButton     matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        Ipiezas % Description
        params
        estimationErrors
        RCal
        TCal  
        Path="G:\Mi unidad\Sincronizado2020\Sincronizado 2020\Unal\2020-2\Robotica\Proyectos\Intermedio";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalibrarCamButton
        function CalibrarCamButtonValueChanged(app, event)
            value = app.CalibrarCamButton.Value;
            [params,estimationErrors,RCal,TCal]=calibrar(app.Path);
        end

        % Value changed function: ClasificarButton
        function ClasificarButtonValueChanged(app, event)
            value = app.ClasificarButton.Value;
            [xyTuercas, xyTornillos,Ic,centrTuercas,centrTornillos]=clasificar(app.Ipiezas,app.params,app.RCal,app.TCal);
        end

        % Value changed function: CargarPiezasButton
        function CargarPiezasButtonValueChanged(app, event)
            value = app.CargarPiezasButton.Value;
            IPiezas=cargarImagen(app.Ipiezas);
        end

        % Callback function
        function PathTextAreaValueChanged(app, event)
                   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CapturarTableroButton = uibutton(app.UIFigure, 'state');
            app.CapturarTableroButton.Text = 'Capturar Tablero';
            app.CalibrarCamButton = uibutton(app.UIFigure, 'state');
            app.CalibrarCamButton.ValueChangedFcn = createCallbackFcn(app, @CalibrarCamButtonValueChanged, true);
            app.CalibrarCamButton.Text = 'Calibrar Cam';
            app.CapturarPiezasButton = uibutton(app.UIFigure, 'state');
            app.CapturarPiezasButton.Text = 'Capturar Piezas';
            app.ClasificarButton = uibutton(app.UIFigure, 'state');
            app.ClasificarButton.ValueChangedFcn = createCallbackFcn(app, @ClasificarButtonValueChanged, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.CargarPiezasButton = uibutton(app.UIFigure, 'state');
            app.CargarPiezasButton.ValueChangedFcn = createCallbackFcn(app, @CargarPiezasButtonValueChanged, true);
            app.CargarPiezasButton.Text = 'Cargar Piezas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end